classdef giaodien3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SpeechFactorValueField    matlab.ui.control.Spinner
        SpeechFactorSpinnerLabel  matlab.ui.control.Label
        PitchShiftbSlider         matlab.ui.control.Slider
        PitchValueField           matlab.ui.control.Spinner
        PitchShiftbSpinnerLabel   matlab.ui.control.Label
        ContinueButton            matlab.ui.control.Button
        PauseButton               matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        PlayProcessedButton       matlab.ui.control.Button
        PlayOriginalButton        matlab.ui.control.Button
        ApplyButton               matlab.ui.control.Button
        SpeechFactorSlider        matlab.ui.control.Slider
        SoundControlLabel         matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        OutputAxes                matlab.ui.control.UIAxes
        InputAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        xInput          % tín hiệu âm thanh gốc
        xPitch          % tín hiệu sau khi pitch shift
        xOutput         % tín hiệu sau khi time stretch
        fs              % tần số lấy mẫu

        % Audio player
        playerOriginal
        playerProcessed
        currentPlayer   % 'original' hoặc 'processed'
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hàm chạy khi app được mở

            % Disable các nút chưa thể dùng
            app.PlayOriginalButton.Enable  = 'off';
            app.PlayProcessedButton.Enable = 'off';
            app.SaveButton.Enable          = 'off';
            app.PauseButton.Enable     = 'off';
            app.ContinueButton.Enable  = 'off';
            app.currentPlayer = '';  
            % ===== TRẠNG THÁI BAN ĐẦU =====
            % b = 0  → không đổi pitch
            % α = 1  → không đổi tốc độ
            % Apply không làm thay đổi âm thanh

            % ===== GIAO DIỆN =====
            
            % ===== NỀN APP =====
            app.UIFigure.Color = [0.94 0.96 1.00];   % xanh pastel

            % ===== LABEL / TIÊU ĐỀ =====
            app.SoundControlLabel.FontColor = [0.15 0.25 0.55];
            app.SoundControlLabel.FontWeight = 'bold';


            % ===== NÚT LOAD =====
            app.LoadButton.Text = '📂 Load WAV';
            app.LoadButton.BackgroundColor = [0.20 0.55 0.90];
            app.LoadButton.FontColor = 'white';
            app.LoadButton.FontWeight = 'bold';

            % ===== NÚT APPLY =====
            app.ApplyButton.Text = '⚙ Apply';
            app.ApplyButton.BackgroundColor = [0.30 0.70 0.45];
            app.ApplyButton.FontColor = 'white';
            app.ApplyButton.FontWeight = 'bold';

            % ===== PLAY ORIGINAL =====
            app.PlayOriginalButton.Text = '▶ Play Original';
            app.PlayOriginalButton.BackgroundColor = [0.25 0.75 0.95];
            app.PlayOriginalButton.FontColor = 'white';

            % ===== PLAY PROCESSED =====
            app.PlayProcessedButton.Text = '▶ Play Processed';
            app.PlayProcessedButton.BackgroundColor = [0.20 0.65 0.50];
            app.PlayProcessedButton.FontColor = 'white';

            % ===== PAUSE / CONTINUE =====
            app.PauseButton.Text = '⏸ Pause';
            app.PauseButton.BackgroundColor = [0.95 0.75 0.25];
            app.PauseButton.FontColor = 'black';

            app.ContinueButton.Text = '⏵ Continue';
            app.ContinueButton.BackgroundColor = [0.60 0.85 0.60];
            app.ContinueButton.FontColor = 'black';

            % ===== SAVE =====
            app.SaveButton.Text = '💾 Save WAV';
            app.SaveButton.BackgroundColor = [0.85 0.35 0.35];
            app.SaveButton.FontColor = 'white';

            % ===== SLIDER =====
            app.SpeechFactorSlider.MajorTicks = 0.5:0.1:1.5;
            app.SpeechFactorSlider.MinorTicks = [];
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load file âm thanh và hiển thị tín hiệu gốc
            [file, path] = uigetfile({'*.wav;*.mp3; *.m4a','Audio Files'});
            if isequal(file,0)
                return;
            end
% Đọc file
            [app.xInput, app.fs] = audioread(fullfile(path,file));

            % mono + normalize
            if size(app.xInput,2) > 1
                app.xInput  = mean(app.xInput,2);
            end
            app.xInput = app.xInput / max(abs(app.xInput)+eps);

            t = (0:length(app.xInput)-1)/app.fs;
            cla(app.InputAxes);
            plot(app.InputAxes, t, app.xInput);
            xlabel(app.InputAxes,'Time (s)');
            ylabel(app.InputAxes,'Amplitude');
            title(app.InputAxes,'Original Speech');

            app.PlayOriginalButton.Enable = 'on';
            app.PlayProcessedButton.Enable = 'off';
            app.SaveButton.Enable = 'off';
        end

        % Value changed function: SpeechFactorSlider
        function SpeechFactorSliderValueChanged(app, event)
            alpha = app.SpeechFactorSlider.Value;
            app.SpeechFactorValueField.Value = alpha;


        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if isempty(app.xInput)
                uialert(app.UIFigure,'Please load audio first','Error');
                return;
            end

            % ===== ĐỌC GIÁ TRỊ ĐIỀU KHIỂN =====
            b     = app.PitchShiftbSlider.Value;     % semitone
            alpha = app.SpeechFactorSlider.Value;    % tốc độ nói

            % ===== TRẠNG THÁI MẶC ĐỊNH: KHÔNG XỬ LÝ =====
            if b == 0 && abs(alpha - 1) < 1e-6
                app.xOutput = app.xInput;
            else
                % ----- BƯỚC 1: ĐỔI TÔNG GIỌNG (NẾU CÓ) -----
                if b ~= 0
                    beta = 2^(b/12);
                    xProc = pitch_shift(app.xInput, app.fs, beta);
                else
                    xProc = app.xInput;
                end

                % ----- BƯỚC 2: ĐỔI TỐC ĐỘ NÓI (NẾU CÓ) -----
                if abs(alpha - 1) > 1e-6
                    app.xOutput = phase_vocoder_time_stretch(xProc, app.fs, alpha);
                else
                    app.xOutput = xProc;
                end
            end

            % ===== HIỂN THỊ =====
            t = (0:length(app.xOutput)-1)/app.fs;
            cla(app.OutputAxes);
            plot(app.OutputAxes, t, app.xOutput);
            title(app.OutputAxes,'Final Output');
            xlabel(app.OutputAxes,'Time (s)');
            ylabel(app.OutputAxes,'Amplitude');

            app.PlayProcessedButton.Enable = 'on';
            app.SaveButton.Enable = 'on';
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            if isempty(app.xInput)
                return;
            end
            app.playerOriginal = audioplayer(app.xInput, app.fs);
            play(app.playerOriginal);
            app.currentPlayer = 'original';
            app.PauseButton.Enable    = 'on';
            app.ContinueButton.Enable = 'off';
        end

        % Button pushed function: PlayProcessedButton
        function PlayProcessedButtonPushed(app, event)
            if isempty(app.xOutput)
                return;
            end
            app.playerProcessed = audioplayer(app.xOutput, app.fs);
            play(app.playerProcessed);
            app.currentPlayer = 'processed';
            app.PauseButton.Enable    = 'on';
            app.ContinueButton.Enable = 'off';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.xOutput)
                uialert(app.UIFigure,'No processed audio','Error');
                return;
            end
            [file,path] = uiputfile('*.wav');
            if isequal(file,0), return; end
            audiowrite(fullfile(path,file),app.xOutput,app.fs);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            switch app.currentPlayer
                case 'original'
                    if ~isempty(app.playerOriginal) && isplaying(app.playerOriginal)
                        pause(app.playerOriginal);
                    end
                case 'processed'
                    if ~isempty(app.playerProcessed) && isplaying(app.playerProcessed)
                        pause(app.playerProcessed);
                    end
            end
            app.PauseButton.Enable    = 'off';
            app.ContinueButton.Enable = 'on';

        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            switch app.currentPlayer
                case 'original'
                    if ~isempty(app.playerOriginal)
                        resume(app.playerOriginal);
                    end
                case 'processed'
                    if ~isempty(app.playerProcessed)
                        resume(app.playerProcessed);
                    end
            end
            app.PauseButton.Enable    = 'on';
            app.ContinueButton.Enable = 'off';
        end

        % Value changed function: PitchShiftbSlider
        function PitchShiftbSliderValueChanged(app, event)
            if isempty(app.xInput)
                % vẫn cập nhật hiển thị dù chưa load audio
            end

            b = round(app.PitchShiftbSlider.Value);
            app.PitchShiftbSlider.Value = b;
            app.PitchValueField.Value = b;

            % chỉ xử lý khi b ≠ 0
            if ~isempty(app.xInput)
                if b == 0
                    app.xPitch = app.xInput;
                else
                    beta = 2^(b/12);
                    app.xPitch = pitch_shift(app.xInput, app.fs, beta);
                end

                t = (0:length(app.xPitch)-1)/app.fs;
                cla(app.OutputAxes);
                plot(app.OutputAxes, t, app.xPitch);
                title(app.OutputAxes,'After Pitch Shift');
                xlabel(app.OutputAxes,'Time (s)');
                ylabel(app.OutputAxes,'Amplitude');

                app.xOutput = [];
                app.PlayProcessedButton.Enable = 'off';
                app.SaveButton.Enable = 'off';
            end
        end

        % Value changed function: SpeechFactorValueField
        function SpeechFactorValueFieldValueChanged(app, event)
            alpha = app.SpeechFactorValueField.Value;
            app.SpeechFactorSlider.Value = alpha;

        end

        % Value changed function: PitchValueField
        function PitchValueFieldValueChanged(app, event)
            b = round(app.PitchValueField.Value);
            app.PitchValueField.Value = b;
            app.PitchShiftbSlider.Value = b;

            % gọi lại xử lý như khi kéo slider
            PitchShiftbSliderValueChanged(app, event);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputAxes = uiaxes(app.UIFigure);
            title(app.InputAxes, 'Original Waveform')
            xlabel(app.InputAxes, 'Time (s)')
            ylabel(app.InputAxes, 'Amplitude')
            zlabel(app.InputAxes, 'Z')
            app.InputAxes.YTick = [0 0.5 1];
            app.OutputAxes = uiaxes(app.UIFigure);
            title(app.OutputAxes, 'Processed Waveform')
            xlabel(app.OutputAxes, 'Time (s)')
            ylabel(app.OutputAxes, 'Amplitude')
            zlabel(app.OutputAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load WAV';
            app.SoundControlLabel = uilabel(app.UIFigure);
            app.SoundControlLabel.Text = 'Sound Control ';
            app.SpeechFactorSlider = uislider(app.UIFigure);
            app.SpeechFactorSlider.Limits = [0.5 1.5];
            app.SpeechFactorSlider.ValueChangedFcn = createCallbackFcn(app, @SpeechFactorSliderValueChanged, true);
            app.SpeechFactorSlider.Value = 1;
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayProcessedButton = uibutton(app.UIFigure, 'push');
            app.PlayProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayProcessedButtonPushed, true);
            app.PlayProcessedButton.Text = 'Play Processed';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save WAV';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.PitchShiftbSpinnerLabel = uilabel(app.UIFigure);
            app.PitchShiftbSpinnerLabel.Text = 'Pitch Shift (b)';
            app.PitchValueField = uispinner(app.UIFigure);
            app.PitchValueField.Limits = [-12 12];
            app.PitchValueField.ValueDisplayFormat = '%d';
            app.PitchValueField.ValueChangedFcn = createCallbackFcn(app, @PitchValueFieldValueChanged, true);
            app.PitchShiftbSlider = uislider(app.UIFigure);
            app.PitchShiftbSlider.Limits = [-12 12];
            app.PitchShiftbSlider.ValueChangedFcn = createCallbackFcn(app, @PitchShiftbSliderValueChanged, true);
            app.SpeechFactorSpinnerLabel = uilabel(app.UIFigure);
            app.SpeechFactorSpinnerLabel.Text = 'Speech Factor (α)';
            app.SpeechFactorValueField = uispinner(app.UIFigure);
            app.SpeechFactorValueField.Step = 0.01;
            app.SpeechFactorValueField.Limits = [0.5 1.5];
            app.SpeechFactorValueField.ValueDisplayFormat = '%.2f';
            app.SpeechFactorValueField.ValueChangedFcn = createCallbackFcn(app, @SpeechFactorValueFieldValueChanged, true);
            app.SpeechFactorValueField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = giaodien3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end